classdef SecantMethod_GUI_Approach_MATLAB_FarmanAli < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SecantMethodImprovedbyFarmanAliUIFigure  matlab.ui.Figure
        Image                     matlab.ui.control.Image
        ResetButton               matlab.ui.control.Button
        NumericalRootFindingUsingtheSecantMethodLabel  matlab.ui.control.Label
        ToleranceEditField        matlab.ui.control.NumericEditField
        ToleranceEditFieldLabel   matlab.ui.control.Label
        RunSecantMethodButton     matlab.ui.control.Button
        IterationsEditField       matlab.ui.control.NumericEditField
        IterationsEditFieldLabel  matlab.ui.control.Label
        fxEditField               matlab.ui.control.EditField
        fxEditFieldLabel          matlab.ui.control.Label
        XEditField_2              matlab.ui.control.NumericEditField
        XEditField_2Label         matlab.ui.control.Label
        XEditField                matlab.ui.control.NumericEditField
        XEditFieldLabel           matlab.ui.control.Label
        UITable                   matlab.ui.control.Table
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function results = func(app,fun,x)
            results=eval(fun)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunSecantMethodButton
        function RunSecantMethodButtonPushed(app, event)
         xi_1=app.XEditField.Value;
            xi=app.XEditField_2.Value;
            tol=app.ToleranceEditField.Value;
            iter=app.IterationsEditField.Value;
            fun=app.fxEditField.Value;
            err=abs(xi-xi_1);
     

            x_val = linspace(-5, 5, 100);% x-axes -5 to 5

            for i = 1:length(x_val)
                x = x_val(i);
                y_val(i) = eval(fun); 
            end
           
            newRow=[];   
            for i=1:1:iter
                if(err>tol)
             err=abs(xi-xi_1);
             fi=func(app,fun,xi);
             fi_1=func(app,fun,xi_1);
             

             x1=xi:0.001:xi_1;
             m1=(fi_1-fi)/(xi_1-xi);
             c1=fi-m1*xi;
             y1=m1*x1+c1;
          
             x3=[xi,xi_1];
             y3=[fi,fi_1];
             plot(app.UIAxes, x_val, y_val,'b',x3,y3,'r*',x1,y1,'r-','LineWidth', 1.5)
             hold(app.UIAxes, 'on');
       
              X_new=xi-(fi*(xi-xi_1))/(fi-fi_1);
              fiP1=func(app,fun,X_new);
              newRow=[newRow;i xi xi_1 fi fi_1 X_new fiP1];
              xi_1=xi;
              xi=X_new;


            app.UITable.Data=[newRow];
            pause(1.5);
                end
           end
          
           
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
    % Clear axes content
    cla(app.UIAxes);               % Clears the plotted data
    title(app.UIAxes, 'Secant Method Graph - FAM');         % Clears the title
    xlabel(app.UIAxes, '');        % Clears x-axis label
    ylabel(app.UIAxes, '');        % Clears y-axis label
    legend(app.UIAxes, 'off');     % Removes legend if present

    % Clear table content
    app.UITable.Data = {};         % Empties the table

    % Clear input fields
    app.XEditField.Value = 0;
    app.XEditField_2.Value = 0;
    app.fxEditField.Value = '';
    app.IterationsEditField.Value = 0;
    app.ToleranceEditField.Value = 0.0001;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.SecantMethodImprovedbyFarmanAliUIFigure = uifigure('Visible', 'off');
            app.SecantMethodImprovedbyFarmanAliUIFigure.Name = 'Secant Method Improved by Farman Ali';
            app.SecantMethodImprovedbyFarmanAliUIFigure.Icon = fullfile(pathToMLAPP, 'Hawdang_Kawa_project', 'Icon.png');
            app.UIAxes = uiaxes(app.SecantMethodImprovedbyFarmanAliUIFigure);
            title(app.UIAxes, 'Secant Method Graph - FAM')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.SecantMethodImprovedbyFarmanAliUIFigure);
            app.UITable.ColumnName = {'i'; 'xᵢ'; 'xᵢ₋₁'; 'f(xᵢ)'; 'f(xᵢ₋₁)'; 'xᵢ₊₁'; 'f(xᵢ₊₁)'};
            app.UITable.RowName = {};
            app.XEditFieldLabel = uilabel(app.SecantMethodImprovedbyFarmanAliUIFigure);
            app.XEditFieldLabel.Text = 'X₀';
            app.XEditField = uieditfield(app.SecantMethodImprovedbyFarmanAliUIFigure, 'numeric');
            app.XEditField.AllowEmpty = 'on';
            app.XEditField_2Label = uilabel(app.SecantMethodImprovedbyFarmanAliUIFigure);
            app.XEditField_2Label.Text = 'X₁';
            app.XEditField_2 = uieditfield(app.SecantMethodImprovedbyFarmanAliUIFigure, 'numeric');
            app.XEditField_2.AllowEmpty = 'on';
            app.fxEditFieldLabel = uilabel(app.SecantMethodImprovedbyFarmanAliUIFigure);
            app.fxEditFieldLabel.Text = 'f(x)';
            app.fxEditField = uieditfield(app.SecantMethodImprovedbyFarmanAliUIFigure, 'text');
            app.IterationsEditFieldLabel = uilabel(app.SecantMethodImprovedbyFarmanAliUIFigure);
            app.IterationsEditFieldLabel.Text = 'Iterations';
            app.IterationsEditField = uieditfield(app.SecantMethodImprovedbyFarmanAliUIFigure, 'numeric');
            app.IterationsEditField.AllowEmpty = 'on';
            app.RunSecantMethodButton = uibutton(app.SecantMethodImprovedbyFarmanAliUIFigure, 'push');
            app.RunSecantMethodButton.ButtonPushedFcn = createCallbackFcn(app, @RunSecantMethodButtonPushed, true);
            app.RunSecantMethodButton.Text = 'Run Secant Method';
            app.ToleranceEditFieldLabel = uilabel(app.SecantMethodImprovedbyFarmanAliUIFigure);
            app.ToleranceEditFieldLabel.Text = 'Tolerance';
            app.ToleranceEditField = uieditfield(app.SecantMethodImprovedbyFarmanAliUIFigure, 'numeric');
            app.ToleranceEditField.Value = 0.0001;
            app.NumericalRootFindingUsingtheSecantMethodLabel = uilabel(app.SecantMethodImprovedbyFarmanAliUIFigure);
            app.NumericalRootFindingUsingtheSecantMethodLabel.Text = 'Numerical Root Finding Using the Secant Method';
            app.ResetButton = uibutton(app.SecantMethodImprovedbyFarmanAliUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Image = uiimage(app.SecantMethodImprovedbyFarmanAliUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'farmanlogo.png');
            % Show the figure after all components are created
            app.SecantMethodImprovedbyFarmanAliUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SecantMethod_GUI_Approach_MATLAB_FarmanAli

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SecantMethodImprovedbyFarmanAliUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SecantMethodImprovedbyFarmanAliUIFigure)
        end
    end
end